classdef app_text < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        Slider         matlab.ui.control.Slider
        SliderLabel    matlab.ui.control.Label
        TextArea       matlab.ui.control.TextArea
        TextAreaLabel  matlab.ui.control.Label
        UIAxes         matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        start_time      datetime      % 记录启动时间
        time_records    duration      % 时间记录数组
        data_records    double        % 数据记录数组
        sampling_timer  timer         % 定时器对象
        is_running      logical = false % 运行状态标志
        
    end
    
    properties (Access = private)
        Property4 % Description
    end
    
    methods (Access = public)
        
        function app  = app_text
            % 初始化组件
            createComponents(app);
            registerApp(app, app.UIFigure);
            
            % 初始化数据存储
            app.time_records = duration.empty;
            app.data_records = double.empty;
            
            % 配置定时器（10ms间隔）
            app.sampling_timer = timer(...
                'ExecutionMode', 'fixedRate',...
                'Period', 0.01,... % 10ms
                'TimerFcn', @(~,~)app.timerCallback);
            
        end


        function delete(app)
            % 清理定时器
            if isvalid(app.sampling_timer)
                stop(app.sampling_timer);
                delete(app.sampling_timer);
            end
            delete(app.UIFigure);
        end
        
    end
    
    % 回调函数
    methods (Access = private)
        function StartButtonPushed(app, ~)
            % 启动/停止数据采集
            if app.is_running
                stop(app.sampling_timer);
                app.StartButton.Text = '启动';
            else
                % 重置记录
                app.start_time = datetime('now', 'Format', 'yyyy-MM-dd HH:mm:ss.SSS');
                app.time_records = duration.empty;
                app.data_records = double.empty;
                
                start(app.sampling_timer);
                app.StartButton.Text = '停止';
            end
            app.is_running = ~app.is_running;
        end

        function timerCallback(app)
            % 定时器回调函数（每10ms执行）
            current_time = datetime('now') - app.start_time;
            current_value = app.Slider.Value;
            
            % 记录数据
            app.time_records(end+1) = current_time;
            app.data_records(end+1) = current_value;
            
            % 更新显示（限制数据量）
            if mod(numel(app.time_records), 10) == 0
                updateDisplay(app);
            end
        end

        function updateDisplay(app)
            % 转换时间为秒
            time_sec = seconds(app.time_records);
            
            % 更新图形
            if isempty(app.UIAxes.Children)
                plot(app.UIAxes, time_sec, app.data_records);
            else
                app.UIAxes.Children.XData = time_sec;
                app.UIAxes.Children.YData = app.data_records;
            end
            
            % 自动调整坐标轴
            xlim(app.UIAxes, [0 max(time_sec)+0.1]);
            ylim(app.UIAxes, app.Slider.Limits);
            
            % 更新文本信息
            app.TextArea.Value{end+1} = sprintf('[%s] 当前值: %.2f',...
                char(datetime('now','Format','HH:mm:ss.SSS')),...
                app.data_records(end));
            
            % 保持文本区域行数
            if numel(app.TextArea.Value) > 20
                app.TextArea.Value(1) = [];
            end
            
            drawnow limitrate;
        end
    end    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: Slider
        function SliderValueChanged(app, event)
               
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Position = [100 100 800 500];
            app.UIFigure.Name = '实时数据采集系统';

            % 坐标轴
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.Position = [200 150 550 300];
            title(app.UIAxes, '实时数据曲线');
            xlabel(app.UIAxes, '时间 (秒)');
            ylabel(app.UIAxes, '传感器值');

            % 滑块
            app.Slider = uislider(app.UIFigure);
            app.Slider.Limits = [0 10];
            app.Slider.Position = [200 100 400 3];

            % 控制按钮
            app.StartButton = uibutton(app.UIFigure, 'push',...
                'Text', '启动',...
                'Position', [50 400 100 30],...
                'ButtonPushedFcn', @app.StartButtonPushed);

            % 文本区域
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Position = [50 50 700 80];
            app.TextArea.Value = {'=== 数据日志 ==='};

            app.UIFigure.Visible = 'on';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'T')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.TextAreaLabel = uilabel(app.UIFigure);
            app.TextAreaLabel.Text = 'Text Area';
            app.TextArea = uitextarea(app.UIFigure);
            app.SliderLabel = uilabel(app.UIFigure);
            app.SliderLabel.Text = 'Slider';
            app.Slider = uislider(app.UIFigure);
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_text

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end